classdef DoAn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        tim_nghiemTab               matlab.ui.container.Tab
        LanLap                      matlab.ui.control.EditField
        SlnlpLabel                  matlab.ui.control.Label
        KetQua                      matlab.ui.control.EditField
        KtqunghimLabel              matlab.ui.control.Label
        NhapPT                      matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel   matlab.ui.control.Label
        PhuongPhap                  matlab.ui.control.DropDown
        PhngphpDropDownLabel_2      matlab.ui.control.Label
        SaiSo                       matlab.ui.control.NumericEditField
        NhpsaisEditFieldLabel       matlab.ui.control.Label
        PhanLyb                     matlab.ui.control.NumericEditField
        PhanLya                     matlab.ui.control.NumericEditField
        NhpkhongphnlyabLabel        matlab.ui.control.Label
        Tinh                        matlab.ui.control.Button
        DoThi                       matlab.ui.control.UIAxes
        noi_suyTab                  matlab.ui.container.Tab
        PhngphpDropDown_2           matlab.ui.control.DropDown
        PhngphpDropDown_2Label      matlab.ui.control.Label
        KQ_DT                       matlab.ui.control.TextArea
        KtquathcnisuyTextAreaLabel  matlab.ui.control.Label
        dulieuy                     matlab.ui.control.NumericEditField
        NhpdliuxLabel_3             matlab.ui.control.Label
        dulieux                     matlab.ui.control.NumericEditField
        NhpdliuxLabel_2             matlab.ui.control.Label
        KQ_NS                       matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel     matlab.ui.control.Label
        giatrinoisuy                matlab.ui.control.NumericEditField
        NhpdliuxLabel               matlab.ui.control.Label
        Xoa                         matlab.ui.control.Button
        OK                          matlab.ui.control.Button
        Start                       matlab.ui.control.Button
        Bang_dulieu                 matlab.ui.control.Table
        Dothi                       matlab.ui.control.UIAxes
        hoi_quyTab                  matlab.ui.container.Tab
        PhuongPhap_2                matlab.ui.control.DropDown
        PhngphpDropDownLabel_3      matlab.ui.control.Label
        KQ_PT                       matlab.ui.control.TextArea
        KtquphngtrnhhiquyLabel      matlab.ui.control.Label
        dulieuy_2                   matlab.ui.control.NumericEditField
        NhpdliuxLabel_6             matlab.ui.control.Label
        dulieux_2                   matlab.ui.control.NumericEditField
        NhpdliuxLabel_5             matlab.ui.control.Label
        KQ_DD                       matlab.ui.control.NumericEditField
        KtqudonLabel                matlab.ui.control.Label
        giatridudoan                matlab.ui.control.NumericEditField
        NhpdliuxLabel_4             matlab.ui.control.Label
        Xoa_2                       matlab.ui.control.Button
        OK_2                        matlab.ui.control.Button
        Start_2                     matlab.ui.control.Button
        Bang_dulieu_2               matlab.ui.control.Table
        Dothi_2                     matlab.ui.control.UIAxes
        dao_hamTab                  matlab.ui.container.Tab
        XaschButton                 matlab.ui.control.Button
        MatrnyEditField             matlab.ui.control.EditField
        MatrnyEditFieldLabel        matlab.ui.control.Label
        MatrnxEditField             matlab.ui.control.EditField
        MatrnxEditFieldLabel        matlab.ui.control.Label
        SaisDropDown                matlab.ui.control.DropDown
        SaisDropDownLabel           matlab.ui.control.Label
        XpxDropDown                 matlab.ui.control.DropDown
        XpxDropDownLabel            matlab.ui.control.Label
        GiiButton                   matlab.ui.control.Button
        KtquEditField               matlab.ui.control.EditField
        KtquEditFieldLabel          matlab.ui.control.Label
        XcntmEditField              matlab.ui.control.NumericEditField
        XcntmEditFieldLabel         matlab.ui.control.Label
        ChshEditField               matlab.ui.control.NumericEditField
        ChshEditFieldLabel          matlab.ui.control.Label
        HmsEditField                matlab.ui.control.EditField
        HmsEditFieldLabel           matlab.ui.control.Label
        tich_phanTab                matlab.ui.container.Tab
        XaschButton_2               matlab.ui.control.Button
        PhngphpDropDown             matlab.ui.control.DropDown
        PhngphpDropDownLabel        matlab.ui.control.Label
        MatrnyEditField_2           matlab.ui.control.EditField
        MatrnyEditField_2Label      matlab.ui.control.Label
        MatrnxEditField_2           matlab.ui.control.EditField
        MatrnxEditField_2Label      matlab.ui.control.Label
        CntrnEditField              matlab.ui.control.NumericEditField
        CntrnEditFieldLabel         matlab.ui.control.Label
        CndiEditField               matlab.ui.control.NumericEditField
        CndiEditFieldLabel          matlab.ui.control.Label
        GiiButton_2                 matlab.ui.control.Button
        KtquEditField_2             matlab.ui.control.EditField
        KtquEditField_2Label        matlab.ui.control.Label
        NhpNEditField               matlab.ui.control.NumericEditField
        NhpNEditFieldLabel          matlab.ui.control.Label
        HmsEditField_2              matlab.ui.control.EditField
        HmsEditField_2Label         matlab.ui.control.Label
        gioi_thieuTab               matlab.ui.container.Tab
        Image3                      matlab.ui.control.Image
        XYDNGCCGIAODINVLUNLSDNGMATLABAPPDESIGNERLabel  matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GiiButton
        function GiiButtonPushed(app, event)
            if and(~isempty(app.HmsEditField.Value), ~isempty(app.ChshEditField.Value)) % Ham so va Chi so h duoc chon
                switch app.XpxDropDown.Value
                    case "Tiến"
                        if app.SaisDropDown.Value == "O(h)"
                            app.KtquEditField.Value = num2str(daoham_tien_fx_Oh(app.HmsEditField.Value, app.ChshEditField.Value, app.XcntmEditField.Value));
                        else
                            app.KtquEditField.Value = num2str(daoham_tien_fx_Oh2(app.HmsEditField.Value, app.ChshEditField.Value, app.XcntmEditField.Value));
                        end
                    case "Lùi"
                        if app.SaisDropDown.Value == "O(h)"
                            app.KtquEditField.Value = num2str(daoham_lui_fx_Oh(app.HmsEditField.Value, app.ChshEditField.Value, app.XcntmEditField.Value));
                        else
                            app.KtquEditField.Value = num2str(daoham_lui_fx_Oh2(app.HmsEditField.Value, app.ChshEditField.Value, app.XcntmEditField.Value));
                        end
                    otherwise
                        if app.SaisDropDown.Value == "O(h)"
                            app.KtquEditField.Value = "Đang nghiên cứu";
                        else
                            app.KtquEditField.Value = num2str(daoham_trungtam_fx_Oh2(app.HmsEditField.Value, app.ChshEditField.Value, app.XcntmEditField.Value));
                        end
                end
            else % Ham so x va y
                switch app.XpxDropDown.Value
                    case "Tiến"
                        if app.SaisDropDown.Value == "O(h)"
                            app.KtquEditField.Value = num2str(daoham_tien_xy_Oh(app.MatrnxEditField.Value, app.MatrnyEditField.Value, app.XcntmEditField.Value));
                        else
                            app.KtquEditField.Value = num2str(daoham_tien_xy_Oh2(app.MatrnxEditField.Value, app.MatrnyEditField.Value, app.XcntmEditField.Value));
                        end
                    case "Lùi"
                        if app.SaisDropDown.Value == "O(h)"
                            app.KtquEditField.Value = num2str(daoham_lui_xy_Oh(app.MatrnxEditField.Value, app.MatrnyEditField.Value, app.XcntmEditField.Value));
                        else
                            app.KtquEditField.Value = num2str(daoham_lui_xy_Oh2(app.MatrnxEditField.Value, app.MatrnyEditField.Value, app.XcntmEditField.Value));
                        end
                    otherwise
                        if app.SaisDropDown.Value == "O(h)"
                            app.KtquEditField.Value = "Đang nghiên cứu";
                        else
                            app.KtquEditField.Value = num2str(daoham_trungtam_xy_Oh2(app.MatrnxEditField.Value, app.MatrnyEditField.Value, app.XcntmEditField.Value));
                        end
                end
            end
        end

        % Value changed function: HmsEditField
        function HmsEditFieldValueChanged(app, event)
            if ~isempty(app.HmsEditField.Value) % Neu fx hoac h duoc chon vo hieu hoa x va y
                app.MatrnxEditField.Enable = "off";
                app.MatrnyEditField.Enable = "off";
            else
                app.MatrnxEditField.Enable = "on";
                app.MatrnyEditField.Enable = "on";
            end
        end

        % Value changed function: ChshEditField
        function ChshEditFieldValueChanged(app, event)
            if app.ChshEditField.Value ~= 0
                app.MatrnxEditField.Enable = "off";
                app.MatrnyEditField.Enable = "off";
            else
                app.MatrnxEditField.Enable = "on";
                app.MatrnyEditField.Enable = "on";
            end
        end

        % Value changed function: MatrnxEditField
        function MatrnxEditFieldValueChanged(app, event)
            if or(~isempty(app.MatrnxEditField.Value), ~isempty(app.MatrnyEditField.Value)) % Nguoc lai voi o tren
                app.HmsEditField.Enable = "off";
                app.ChshEditField.Enable = "off";
            else
                app.HmsEditField.Enable = "on";
                app.ChshEditField.Enable = "on";
            end
        end

        % Value changed function: MatrnyEditField
        function MatrnyEditFieldValueChanged(app, event)
            if or(~isempty(app.MatrnxEditField.Value), ~isempty(app.MatrnyEditField.Value))
                app.HmsEditField.Enable = "off";
                app.ChshEditField.Enable = "off";
            else
                app.HmsEditField.Enable = "on";
                app.ChshEditField.Enable = "on";
            end
        end

        % Value changed function: HmsEditField_2
        function HmsEditField_2ValueChanged(app, event)
            % Chua nghien cuu Tich phan hinh thang x, y
%             if ~isempty(app.HmsEditField_2.Value)
%                 app.MatrnxEditField_2.Enable = "off";
%                 app.MatrnyEditField_2.Enable = "off";
%             else
%                 app.MatrnxEditField_2.Enable = "on";
%                 app.MatrnyEditField_2.Enable = "on";
%             end
        end

        % Value changed function: MatrnxEditField_2
        function MatrnxEditField_2ValueChanged(app, event)
            % Chua nghien cuu Tich phan hinh thang x, y
%             if ~isempty(app.MatrnxEditField_2.Value)
%                 app.HmsEditField_2.Enable = "off";
%             else
%                 app.HmsEditField_2.Enable = "on";
%             end
        end

        % Value changed function: MatrnyEditField_2
        function MatrnyEditField_2ValueChanged(app, event)
            % Chua nghien cuu Tich phan hinh thang x, y
%             if ~isempty(app.MatrnyEditField_2.Value)
%                 app.HmsEditField_2.Enable = "off";
%             else
%                 app.HmsEditField_2.Enable = "on";
%             end
        end

        % Button pushed function: GiiButton_2
        function GiiButton_2Pushed(app, event)
            if app.NhpNEditField.Value <= 0
                app.KtquEditField.Value = "Error: N <= 0";
            else
                switch app.PhngphpDropDown.Value
                    case "Hình thang"
                        app.KtquEditField_2.Value = num2str(tichphan_hinhthang(app.HmsEditField_2.Value, app.CndiEditField.Value, app.CntrnEditField.Value, app.NhpNEditField.Value));
                    case "Simpson 1/3"
                        app.KtquEditField_2.Value = num2str(tichphan_simpson_13(app.HmsEditField_2.Value, app.CndiEditField.Value, app.CntrnEditField.Value, app.NhpNEditField.Value));
                    otherwise
                        app.KtquEditField_2.Value = num2str(tichphan_simpson_38(app.HmsEditField_2.Value, app.CndiEditField.Value, app.CntrnEditField.Value, app.NhpNEditField.Value));
                end
            end
        end

        % Value changed function: PhngphpDropDown
        function PhngphpDropDownValueChanged(app, event)
            value = app.PhngphpDropDown.Value;
            if value == "Simpson 3/8"
                msgbox("N trong phương pháp này phải chia hết cho 3", "Lưu ý");
            end
        end

        % Button pushed function: OK
        function OKButtonPushed(app, event)
            % Lấy giá trị từ dulieux và dulieu y
            newValue = app.dulieux.Value;
            newValue1 = app.dulieuy.Value;
            
            % Lấy dữ liệu hiện tại của bảng
            data = app.Bang_dulieu.Data;

            % Thêm giá trị mới vào bảng
            newRow = [newValue, newValue1];
            data = [data; newRow];

            % Cập nhật dữ liệu mới trong bảng
            app.Bang_dulieu.Data = data;

            % Chuyển đổi các giá trị số dài thành chuỗi
            dataStrings = string(data);

            % Hiển thị bảng trong App Designer
            app.Bang_dulieu.Data = dataStrings;
        end

        % Button pushed function: Xoa
        function XoaButtonPushed(app, event)
            % Lấy dữ liệu hiện tại của bảng
            data = app.Bang_dulieu.Data;

            % Kiểm tra nếu có ít nhất 1 hàng trong bảng
            if ~isempty(data)
                % Xóa hàng cuối cùng
                data(end, :) = [];
            end

            % Cập nhật dữ liệu mới trong mảng
            app.Bang_dulieu.Data = data;
        end

        % Button pushed function: OK_2
        function OK_2ButtonPushed(app, event)
            % Lấy giá trị từ dulieux và dulieu y
            newValue = app.dulieux_2.Value;
            newValue1 = app.dulieuy_2.Value;
            
            % Lấy dữ liệu hiện tại của bảng
            data = app.Bang_dulieu_2.Data;

            % Thêm giá trị mới vào bảng
            newRow = [newValue, newValue1];
            data = [data; newRow];

            % Cập nhật dữ liệu mới trong bảng
            app.Bang_dulieu_2.Data = data;

            % Chuyển đổi các giá trị số dài thành chuỗi
            dataStrings = string(data);

            % Hiển thị bảng trong App Designer
            app.Bang_dulieu_2.Data = dataStrings;
        end

        % Button pushed function: Xoa_2
        function Xoa_2ButtonPushed(app, event)
            % Lấy dữ liệu hiện tại của bảng
            data = app.Bang_dulieu_2.Data;

            % Kiểm tra nếu có ít nhất 1 hàng trong bảng
            if ~isempty(data)
                % Xóa hàng cuối cùng
                data(end, :) = [];
            end

            % Cập nhật dữ liệu mới trong mảng
            app.Bang_dulieu_2.Data = data;
        end

        % Button pushed function: Tinh
        function TinhButtonPushed(app, event)
            if ~isempty(app.NhapPT.Value)
                cla(app.DoThi);
                fx = app.NhapPT.Value;
                a = app.PhanLya.Value;
                b = app.PhanLyb.Value;
                saiso = app.SaiSo.Value;
                combobox = app.PhuongPhap.Value;
                nghiem = 0;
                lap = 0;
                if(strcmp(combobox, 'Chia đôi'))
                    [nghiem,lap] = ChiaDoi(fx,a,b,saiso);  
                    fx = str2func(['@(x)',fx]);
                    hold(app.DoThi,'on');
                    plot(app.DoThi,linspace(a,b,10),fx(linspace(a,b,10)));
                    plot(app.DoThi,linspace(a,b,10),zeros(1,10));
                elseif(strcmp(combobox, 'Lặp'))
                    [nghiem,lap] = Lap(fx,a,b,saiso);
                    fx = str2func(['@(x)',fx]);
                    hold(app.DoThi,'on');
                    plot(app.DoThi,linspace(a,b,10),fx(linspace(a,b,10)));
                    plot(app.DoThi,linspace(a,b,10),linspace(a,b,10));
                elseif(strcmp(combobox, 'Tiếp tuyến'))
                    [nghiem,lap] = TiepTuyen(fx,a,b,saiso);
                    fx = str2func(['@(x)',fx]);
                    hold(app.DoThi,'on');
                    plot(app.DoThi,linspace(a,b,10),fx(linspace(a,b,10)));
                    plot(app.DoThi,linspace(a,b,10),zeros(1,10));
                end
                app.KetQua.Value = nghiem;
                app.LanLap.Value = lap;
            end
        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            cla(app.Dothi);
            % Lấy giá trị nội suy
            x_ns = app.giatrinoisuy.Value;

            % Lấy dữ liệu hiện tại của bảng
            dataStrings = app.Bang_dulieu.Data;

            % Chuyển đổi từ chuỗi về số
            dataNumeric = str2double(dataStrings);

            % Lấy dữ liệu x và y từ bảng
            xa = dataNumeric(:, 1);
            ya = dataNumeric(:, 2);

            % Kiểm tra phương pháp được chọn
            phuongphap = app.PhngphpDropDown.Value;

            % Lagrange
            if strcmp(phuongphap, 'Lagrange')
                
                n = length(xa);
                syms x;
                polynomial = 0;
                sum = 0;
    
                for i = 1:n
                    tmp = ya(i);
                    tmp1 = ya(i);
                    for j = 1:n
                        if j ~= i
                            tmp = tmp * (x_ns - xa(j)) / (xa(i) - xa(j));
                            tmp1 = tmp1 * (x - xa(j)) / (xa(i) - xa(j));
                        end
                    end
                    sum = sum + tmp;
                    polynomial = polynomial + tmp1;
                end
                result = sum;
                Dathuc = simplify(polynomial); % Kết quả đa thức
            else
                % Newton
                n = length(xa); % Kiểm tra số lượng các điểm
                da = ya;
                syms x;

                for i = 1:n
                    for j = 1:(i - 1)
                        da(i) = (da(j) - da(i)) / (xa(j) - xa(i));
                    end
                end

                n = length(da);
                result = da(n);
                Dathuc = da(n);
                for i = (n-1):-1:1
                    result = result * (x_ns - xa(i)) + da(i);
                    Dathuc = Dathuc * (x - xa(i)) + da(i);	
                end
                Dathuc = simplify(Dathuc);
            end

            % Hiển thị kết quả nội suy
            app.KQ_NS.Value = result;

            % Hiển thị đa thức nội suy
            app.KQ_DT.Value = char(Dathuc);

            % Vẽ đồ thị
            % Chuyển đổi đa thức nội suy thành hàm số
            f = matlabFunction(Dathuc);

            % Tạo dữ liệu để vẽ đồ thị
            x_data = linspace(min(xa), max(xa), 1000); % Dữ liệu x cho đồ thị
            y_interpolated = f(x_data); % Đa thức nội suy tại các điểm x_data

            % Vẽ đồ thị hàm số nội suy
            plot(app.Dothi, x_data, y_interpolated, 'b-', 'LineWidth', 2);
            hold(app.Dothi, 'on');

            % Vẽ dữ liệu thực
            plot(app.Dothi, xa, ya, 'ro', 'MarkerSize', 8);
        end

        % Button pushed function: Start_2
        function Start_2ButtonPushed(app, event)
            cla(app.Dothi_2)             
            x_pre = app.giatridudoan.Value;
            % Lấy giá trị 
            % Lấy dữ liệu hiện tại của bảng
            dataStrings = app.Bang_dulieu_2.Data;
            % Chuyển đổi từ chuỗi về số
            dataNumeric = str2double(dataStrings);
            % Lấy dữ liệu x và y từ bảng
            xa = dataNumeric(:, 1);
            ya = dataNumeric(:, 2);
            % Kiểm tra phương pháp được chọn
            phuongphap = app.PhuongPhap_2.Value;
            % TT
            if strcmp(phuongphap, 'Tuyến tính')
                    n = length(xa);
                    sumx = sum(xa);
                    sumy = sum(ya);
                    sumxy = sum(xa.*ya);
                    sumx2 = sum(xa.*xa);
                    xm = sumx/n;
                    ym = sumy/n;
                    a1 = (n*sumxy - sumx*sumy)/(n*sumx2 - sumx*sumx);
                    a0 = ym - a1*xm;
                    X = min(xa):0.1:max(xa);
                    Y = a1 * X + a0;
                    % Giá trị dự đoán
                    y_pre = a1 * x_pre + a0;
                    app.KQ_DD.Value = y_pre;
                    % In ra phương trình 
                    app.KQ_PT.Value = sprintf('y = %.4f* x +%.4f', a1,a0);
            end
            %Hàm mũ
            if strcmp(phuongphap, 'Hàm mũ')
                    n = length(xa);
                    sumx = sum(log10(xa));
                    sumy = sum(log10(ya));
                    sumxy = sum(log10(xa).*log10(ya));
                    sumx2 = sum(log10(xa).*log10(xa));
                    xm = sumx/n;
                    ym = sumy/n;
                    a1 = (n*sumxy - sumx*sumy)/(n*sumx2 - sumx*sumx);
                    a0 = 10^(ym - a1*xm);
                    X = min(xa):0.01:max(xa);
                    Y = a0 * X.^a1;
                    y_pre = a0*x_pre.^a1;
                    % Giá trị dự đoán
                    app.KQ_DD.Value = y_pre;
                    % In ra phương trình 
                    app.KQ_PT.Value =['y = ', sprintf('%.4f', a0),' * x^', sprintf('%.4f', a1)];
            end
            %Hàm Logarit
            if strcmp(phuongphap, 'Logarit')
                    n = length(xa);
                    sumx = sum(xa);
                    sumy = sum(log(ya));
                    sumxy = sum(xa.*log(ya));
                    sumx2 = sum(xa.*xa);
                    xm = sumx/n;
                    ym = sumy/n;
                    a1 = (n*sumxy - sumx*sumy)/(n*sumx2 - sumx*sumx);
                    a0 = exp(ym - a1*xm);
                    X = min(xa):0.01:max(xa);
                    Y = a0 * exp(a1*X);
                    y_pre = a0*exp(a1*x_pre);
                    % Giá trị dự đoán
                    app.KQ_DD.Value = y_pre;
                    % In ra phương trình
                    app.KQ_PT.Value = ['y = ', sprintf('%.4f', a0), ' * exp(', sprintf('%.4f', a1), ' * x)'];
            end
            % Vẽ đồ thị
            plot(app.Dothi_2,xa,ya,'o');
            hold(app.Dothi_2, 'on');
            plot(app.Dothi_2,X,Y,'r');
            hold(app.Dothi_2, 'off');
            legend(app.Dothi_2,'Dữ liệu thực', 'Đường hồi quy');
        end

        % Value changed function: XpxDropDown
        function XpxDropDownValueChanged(app, event)
            value = app.XpxDropDown.Value;
            if value == "Trung tâm"
                app.SaisDropDown.Value = "O(h^2)";
                app.SaisDropDown.Enable = "off";
            else
                app.SaisDropDown.Enable = "on";
            end
        end

        % Button pushed function: XaschButton
        function XaschButtonPushed(app, event)
            app.HmsEditField.Enable = "on";
            app.ChshEditField.Enable = "on";
            app.MatrnxEditField.Enable = "on";
            app.MatrnyEditField.Enable = "on";
            app.HmsEditField.Value = "";
            app.ChshEditField.Value = 0;
            app.MatrnxEditField.Value = "";
            app.MatrnyEditField.Value = "";
            app.XpxDropDown.Value = "Tiến";
            app.SaisDropDown.Value = "O(h)";
            app.XcntmEditField.Value = 0;
            app.KtquEditField.Value = "";
        end

        % Button pushed function: XaschButton_2
        function XaschButton_2Pushed(app, event)
            app.HmsEditField_2.Enable = "on";
            % Chua nghien cuu Tich phan hinh thang x, y
%             app.MatrnxEditField_2.Enable = "on";
%             app.MatrnyEditField_2.Enable = "on";
            app.HmsEditField_2.Value = "";
            % Chua nghien cuu Tich phan hinh thang x, y
%             app.MatrnxEditField_2.Value = "";
%             app.MatrnyEditField_2.Value = "";
            app.NhpNEditField.Value = 0;
            app.KtquEditField_2.Value = "";
            app.PhngphpDropDown.Value = "Hình thang";
            app.CntrnEditField.Value = 0;
            app.CndiEditField.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.tim_nghiemTab = uitab(app.TabGroup);
            app.tim_nghiemTab.Title = 'tim_nghiem';
            app.DoThi = uiaxes(app.tim_nghiemTab);
            title(app.DoThi, 'Đồ thị hàm số tìm nghiệm')
            xlabel(app.DoThi, 'X')
            ylabel(app.DoThi, 'Y')
            zlabel(app.DoThi, 'Z')
            app.Tinh = uibutton(app.tim_nghiemTab, 'push');
            app.Tinh.ButtonPushedFcn = createCallbackFcn(app, @TinhButtonPushed, true);
            app.Tinh.Text = 'Tính';
            app.NhpkhongphnlyabLabel = uilabel(app.tim_nghiemTab);
            app.NhpkhongphnlyabLabel.Text = 'Nhập khoảng phân ly[a, b]';
            app.PhanLya = uieditfield(app.tim_nghiemTab, 'numeric');
            app.PhanLyb = uieditfield(app.tim_nghiemTab, 'numeric');
            app.NhpsaisEditFieldLabel = uilabel(app.tim_nghiemTab);
            app.NhpsaisEditFieldLabel.Text = 'Nhập sai số ';
            app.SaiSo = uieditfield(app.tim_nghiemTab, 'numeric');
            app.PhngphpDropDownLabel_2 = uilabel(app.tim_nghiemTab);
            app.PhngphpDropDownLabel_2.Text = 'Phương pháp';
            app.PhuongPhap = uidropdown(app.tim_nghiemTab);
            app.PhuongPhap.Items = {'Chia đôi', 'Lặp', 'Tiếp tuyến'};
            app.PhuongPhap.Value = 'Lặp';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.tim_nghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhapPT = uieditfield(app.tim_nghiemTab, 'text');
            app.KtqunghimLabel = uilabel(app.tim_nghiemTab);
            app.KtqunghimLabel.Text = 'Kết quả nghiệm';
            app.KetQua = uieditfield(app.tim_nghiemTab, 'text');
            app.KetQua.Editable = 'off';
            app.SlnlpLabel = uilabel(app.tim_nghiemTab);
            app.SlnlpLabel.Text = 'Số lần lặp';
            app.LanLap = uieditfield(app.tim_nghiemTab, 'text');
            app.LanLap.Editable = 'off';
            app.noi_suyTab = uitab(app.TabGroup);
            app.noi_suyTab.Title = 'noi_suy';
            app.Dothi = uiaxes(app.noi_suyTab);
            title(app.Dothi, 'Đồ thị hàm số nội suy và dữ liệu thực')
            xlabel(app.Dothi, 'X')
            ylabel(app.Dothi, 'Y')
            zlabel(app.Dothi, 'Z')
            app.Bang_dulieu = uitable(app.noi_suyTab);
            app.Bang_dulieu.ColumnName = {'Dữ liệu x'; 'Dữ liệu y'};
            app.Bang_dulieu.RowName = {};
            app.Start = uibutton(app.noi_suyTab, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Text = 'Tính';
            app.OK = uibutton(app.noi_suyTab, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OK.Text = 'Thêm biến';
            app.Xoa = uibutton(app.noi_suyTab, 'push');
            app.Xoa.ButtonPushedFcn = createCallbackFcn(app, @XoaButtonPushed, true);
            app.Xoa.Text = 'Xóa biến';
            app.NhpdliuxLabel = uilabel(app.noi_suyTab);
            app.NhpdliuxLabel.Text = 'Nhập giá trị nội suy';
            app.giatrinoisuy = uieditfield(app.noi_suyTab, 'numeric');
            app.giatrinoisuy.ValueDisplayFormat = '%11.6g';
            app.KtqunisuyEditFieldLabel = uilabel(app.noi_suyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KQ_NS = uieditfield(app.noi_suyTab, 'numeric');
            app.KQ_NS.ValueDisplayFormat = '%11.6g';
            app.NhpdliuxLabel_2 = uilabel(app.noi_suyTab);
            app.NhpdliuxLabel_2.Text = 'Nhập dữ liệu x';
            app.dulieux = uieditfield(app.noi_suyTab, 'numeric');
            app.dulieux.ValueDisplayFormat = '%11.6g';
            app.NhpdliuxLabel_3 = uilabel(app.noi_suyTab);
            app.NhpdliuxLabel_3.Text = 'Nhập dữ liệu y';
            app.dulieuy = uieditfield(app.noi_suyTab, 'numeric');
            app.dulieuy.ValueDisplayFormat = '%11.6g';
            app.KtquathcnisuyTextAreaLabel = uilabel(app.noi_suyTab);
            app.KtquathcnisuyTextAreaLabel.Text = 'Kết quả đa thức nội suy';
            app.KQ_DT = uitextarea(app.noi_suyTab);
            app.PhngphpDropDown_2Label = uilabel(app.noi_suyTab);
            app.PhngphpDropDown_2Label.Text = 'Phương pháp';
            app.PhngphpDropDown_2 = uidropdown(app.noi_suyTab);
            app.PhngphpDropDown_2.Items = {'Lagrange', 'Newton'};
            app.PhngphpDropDown_2.Value = 'Lagrange';
            app.hoi_quyTab = uitab(app.TabGroup);
            app.hoi_quyTab.Title = 'hoi_quy';
            app.Dothi_2 = uiaxes(app.hoi_quyTab);
            title(app.Dothi_2, 'Đồ thị hàm hồi quy và dữ liệu thực')
            xlabel(app.Dothi_2, 'X')
            ylabel(app.Dothi_2, 'Y')
            zlabel(app.Dothi_2, 'Z')
            app.Bang_dulieu_2 = uitable(app.hoi_quyTab);
            app.Bang_dulieu_2.ColumnName = {'Dữ liệu x'; 'Dữ liệu y'};
            app.Bang_dulieu_2.RowName = {};
            app.Start_2 = uibutton(app.hoi_quyTab, 'push');
            app.Start_2.ButtonPushedFcn = createCallbackFcn(app, @Start_2ButtonPushed, true);
            app.Start_2.Text = 'Tính';
            app.OK_2 = uibutton(app.hoi_quyTab, 'push');
            app.OK_2.ButtonPushedFcn = createCallbackFcn(app, @OK_2ButtonPushed, true);
            app.OK_2.Text = 'Thêm biến';
            app.Xoa_2 = uibutton(app.hoi_quyTab, 'push');
            app.Xoa_2.ButtonPushedFcn = createCallbackFcn(app, @Xoa_2ButtonPushed, true);
            app.Xoa_2.Text = 'Xóa biến';
            app.NhpdliuxLabel_4 = uilabel(app.hoi_quyTab);
            app.NhpdliuxLabel_4.Text = 'Nhập giá trị dự đoán';
            app.giatridudoan = uieditfield(app.hoi_quyTab, 'numeric');
            app.giatridudoan.ValueDisplayFormat = '%11.6g';
            app.KtqudonLabel = uilabel(app.hoi_quyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán';
            app.KQ_DD = uieditfield(app.hoi_quyTab, 'numeric');
            app.KQ_DD.ValueDisplayFormat = '%11.6g';
            app.NhpdliuxLabel_5 = uilabel(app.hoi_quyTab);
            app.NhpdliuxLabel_5.Text = 'Nhập dữ liệu x';
            app.dulieux_2 = uieditfield(app.hoi_quyTab, 'numeric');
            app.dulieux_2.ValueDisplayFormat = '%11.6g';
            app.NhpdliuxLabel_6 = uilabel(app.hoi_quyTab);
            app.NhpdliuxLabel_6.Text = 'Nhập dữ liệu y';
            app.dulieuy_2 = uieditfield(app.hoi_quyTab, 'numeric');
            app.dulieuy_2.ValueDisplayFormat = '%11.6g';
            app.KtquphngtrnhhiquyLabel = uilabel(app.hoi_quyTab);
            app.KtquphngtrnhhiquyLabel.Text = 'Kết quả phương trình hồi quy';
            app.KQ_PT = uitextarea(app.hoi_quyTab);
            app.PhngphpDropDownLabel_3 = uilabel(app.hoi_quyTab);
            app.PhngphpDropDownLabel_3.Text = 'Phương pháp';
            app.PhuongPhap_2 = uidropdown(app.hoi_quyTab);
            app.PhuongPhap_2.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.PhuongPhap_2.Value = 'Tuyến tính';
            app.dao_hamTab = uitab(app.TabGroup);
            app.dao_hamTab.Title = 'dao_ham';
            app.HmsEditFieldLabel = uilabel(app.dao_hamTab);
            app.HmsEditFieldLabel.Text = 'Hàm số';
            app.HmsEditField = uieditfield(app.dao_hamTab, 'text');
            app.HmsEditField.ValueChangedFcn = createCallbackFcn(app, @HmsEditFieldValueChanged, true);
            app.ChshEditFieldLabel = uilabel(app.dao_hamTab);
            app.ChshEditFieldLabel.Text = 'Chỉ số h';
            app.ChshEditField = uieditfield(app.dao_hamTab, 'numeric');
            app.ChshEditField.ValueChangedFcn = createCallbackFcn(app, @ChshEditFieldValueChanged, true);
            app.XcntmEditFieldLabel = uilabel(app.dao_hamTab);
            app.XcntmEditFieldLabel.Text = 'X cần tìm';
            app.XcntmEditField = uieditfield(app.dao_hamTab, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.dao_hamTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KtquEditField = uieditfield(app.dao_hamTab, 'text');
            app.KtquEditField.Editable = 'off';
            app.GiiButton = uibutton(app.dao_hamTab, 'push');
            app.GiiButton.ButtonPushedFcn = createCallbackFcn(app, @GiiButtonPushed, true);
            app.GiiButton.Text = 'Giải';
            app.XpxDropDownLabel = uilabel(app.dao_hamTab);
            app.XpxDropDownLabel.Text = 'Xấp xỉ';
            app.XpxDropDown = uidropdown(app.dao_hamTab);
            app.XpxDropDown.Items = {'Tiến', 'Lùi', 'Trung tâm'};
            app.XpxDropDown.ValueChangedFcn = createCallbackFcn(app, @XpxDropDownValueChanged, true);
            app.XpxDropDown.Value = 'Tiến';
            app.SaisDropDownLabel = uilabel(app.dao_hamTab);
            app.SaisDropDownLabel.Text = 'Sai số';
            app.SaisDropDown = uidropdown(app.dao_hamTab);
            app.SaisDropDown.Items = {'O(h)', 'O(h^2)'};
            app.SaisDropDown.Value = 'O(h)';
            app.MatrnxEditFieldLabel = uilabel(app.dao_hamTab);
            app.MatrnxEditFieldLabel.Text = 'Ma trận x';
            app.MatrnxEditField = uieditfield(app.dao_hamTab, 'text');
            app.MatrnxEditField.ValueChangedFcn = createCallbackFcn(app, @MatrnxEditFieldValueChanged, true);
            app.MatrnyEditFieldLabel = uilabel(app.dao_hamTab);
            app.MatrnyEditFieldLabel.Text = 'Ma trận y';
            app.MatrnyEditField = uieditfield(app.dao_hamTab, 'text');
            app.MatrnyEditField.ValueChangedFcn = createCallbackFcn(app, @MatrnyEditFieldValueChanged, true);
            app.XaschButton = uibutton(app.dao_hamTab, 'push');
            app.XaschButton.ButtonPushedFcn = createCallbackFcn(app, @XaschButtonPushed, true);
            app.XaschButton.Text = 'Xóa sạch';
            app.tich_phanTab = uitab(app.TabGroup);
            app.tich_phanTab.Title = 'tich_phan';
            app.HmsEditField_2Label = uilabel(app.tich_phanTab);
            app.HmsEditField_2Label.Text = 'Hàm số';
            app.HmsEditField_2 = uieditfield(app.tich_phanTab, 'text');
            app.HmsEditField_2.ValueChangedFcn = createCallbackFcn(app, @HmsEditField_2ValueChanged, true);
            app.NhpNEditFieldLabel = uilabel(app.tich_phanTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.NhpNEditField = uieditfield(app.tich_phanTab, 'numeric');
            app.KtquEditField_2Label = uilabel(app.tich_phanTab);
            app.KtquEditField_2Label.Text = 'Kết quả';
            app.KtquEditField_2 = uieditfield(app.tich_phanTab, 'text');
            app.KtquEditField_2.Editable = 'off';
            app.GiiButton_2 = uibutton(app.tich_phanTab, 'push');
            app.GiiButton_2.ButtonPushedFcn = createCallbackFcn(app, @GiiButton_2Pushed, true);
            app.GiiButton_2.Text = 'Giải';
            app.CndiEditFieldLabel = uilabel(app.tich_phanTab);
            app.CndiEditFieldLabel.Text = 'Cận dưới';
            app.CndiEditField = uieditfield(app.tich_phanTab, 'numeric');
            app.CntrnEditFieldLabel = uilabel(app.tich_phanTab);
            app.CntrnEditFieldLabel.Text = 'Cận trên';
            app.CntrnEditField = uieditfield(app.tich_phanTab, 'numeric');
            app.MatrnxEditField_2Label = uilabel(app.tich_phanTab);
            app.MatrnxEditField_2Label.Enable = 'off';
            app.MatrnxEditField_2Label.Text = 'Ma trận x';
            app.MatrnxEditField_2 = uieditfield(app.tich_phanTab, 'text');
            app.MatrnxEditField_2.ValueChangedFcn = createCallbackFcn(app, @MatrnxEditField_2ValueChanged, true);
            app.MatrnxEditField_2.Enable = 'off';
            app.MatrnyEditField_2Label = uilabel(app.tich_phanTab);
            app.MatrnyEditField_2Label.Enable = 'off';
            app.MatrnyEditField_2Label.Text = 'Ma trận y';
            app.MatrnyEditField_2 = uieditfield(app.tich_phanTab, 'text');
            app.MatrnyEditField_2.ValueChangedFcn = createCallbackFcn(app, @MatrnyEditField_2ValueChanged, true);
            app.MatrnyEditField_2.Enable = 'off';
            app.PhngphpDropDownLabel = uilabel(app.tich_phanTab);
            app.PhngphpDropDownLabel.Text = 'Phương pháp';
            app.PhngphpDropDown = uidropdown(app.tich_phanTab);
            app.PhngphpDropDown.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.PhngphpDropDown.ValueChangedFcn = createCallbackFcn(app, @PhngphpDropDownValueChanged, true);
            app.PhngphpDropDown.Value = 'Hình thang';
            app.XaschButton_2 = uibutton(app.tich_phanTab, 'push');
            app.XaschButton_2.ButtonPushedFcn = createCallbackFcn(app, @XaschButton_2Pushed, true);
            app.XaschButton_2.Text = 'Xóa sạch';
            app.gioi_thieuTab = uitab(app.TabGroup);
            app.gioi_thieuTab.Title = 'gioi_thieu';
            app.Image = uiimage(app.gioi_thieuTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Functions', 'gioi_thieu', 'gioithieu.png');
            app.Image2 = uiimage(app.gioi_thieuTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'hcmus_logo.png');
            app.XYDNGCCGIAODINVLUNLSDNGMATLABAPPDESIGNERLabel = uilabel(app.gioi_thieuTab);
            app.XYDNGCCGIAODINVLUNLSDNGMATLABAPPDESIGNERLabel.Text = 'XÂY DỰNG CÁC GIAO DIỆN VÀ LUẬN LÝ SỬ DỤNG MATLAB APP DESIGNER';
            app.Image3 = uiimage(app.gioi_thieuTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'fetel_logo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoAn

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end